classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InitializationTab               matlab.ui.container.Tab
        FirstcoordinateofobjectPanel    matlab.ui.container.Panel
        ymmLabel                        matlab.ui.control.Label
        y1                              matlab.ui.control.NumericEditField
        xmmLabel                        matlab.ui.control.Label
        x1                              matlab.ui.control.NumericEditField
        SecondcoordinateofobjectPanel   matlab.ui.container.Panel
        ymmLabel_2                      matlab.ui.control.Label
        y2                              matlab.ui.control.NumericEditField
        xmmLabel_2                      matlab.ui.control.Label
        x2                              matlab.ui.control.NumericEditField
        HeightofobjectPanel             matlab.ui.container.Panel
        hmmLabel                        matlab.ui.control.Label
        h                               matlab.ui.control.NumericEditField
        SizeofmeasurementareaPanel      matlab.ui.container.Panel
        ymmLabel_3                      matlab.ui.control.Label
        size_y                          matlab.ui.control.NumericEditField
        xmmLabel_3                      matlab.ui.control.Label
        size_x                          matlab.ui.control.NumericEditField
        hmmLabel_2                      matlab.ui.control.Label
        size_h                          matlab.ui.control.NumericEditField
        MeasuringresolutionmmDropDownLabel  matlab.ui.control.Label
        MeasuringresolutionmmDropDown   matlab.ui.control.DropDown
        GenerateButton                  matlab.ui.control.Button
        VisualizationTab                matlab.ui.container.Tab
        SurfaceplotPanel                matlab.ui.container.Panel
        DimensionsofcutoutDropDown_2Label  matlab.ui.control.Label
        DimensionsofcutoutDropDown_2    matlab.ui.control.DropDown
        PositionofcutoutEditField_2Label  matlab.ui.control.Label
        PositionofcutoutEditField_2     matlab.ui.control.NumericEditField
        ComponentofBfieldDropDown_3Label  matlab.ui.control.Label
        ComponentofBfieldDropDown_3     matlab.ui.control.DropDown
        GenerateplotButton_3            matlab.ui.control.Button
        DscatterplotPanel_2             matlab.ui.container.Panel
        ComponentofBfieldDropDown_2Label  matlab.ui.control.Label
        ComponentofBfieldDropDown_2     matlab.ui.control.DropDown
        PercentageofdatatobekeptEditFieldLabel  matlab.ui.control.Label
        PercentageofdatatobekeptEditField  matlab.ui.control.NumericEditField
        GenerateplotButton_2            matlab.ui.control.Button
        DscatterplotPanel               matlab.ui.container.Panel
        PositionofcutoutEditFieldLabel  matlab.ui.control.Label
        PositionofcutoutEditField       matlab.ui.control.NumericEditField
        GenerateplotButton              matlab.ui.control.Button
        ComponentofBfieldDropDownLabel  matlab.ui.control.Label
        ComponentofBfieldDropDown       matlab.ui.control.DropDown
        DimensionsofcutoutDropDownLabel  matlab.ui.control.Label
        DimensionsofcutoutDropDown      matlab.ui.control.DropDown
        LoadmeasurementsButton          matlab.ui.control.Button
        QuiverplotPanel                 matlab.ui.container.Panel
        PercentageDatacutoffEditFieldLabel  matlab.ui.control.Label
        PercentageDatacutoffEditField   matlab.ui.control.NumericEditField
        GenerateplotButton_4            matlab.ui.control.Button
    end


    properties (Access = private)
        s=struct('dim1_str', 'x', 'dim2_str', 'y', 'B_choice', 'Bx', 'dim3_min', 19.5, 'dim3_max', 20.5, 'sort', 0.00);
        %I create the struct
        data=0;
        field=0;% Description
    end


    methods (Access = private)

        % Value changed function: ComponentofBfieldDropDown
        function ComponentofBfieldDropDownValueChanged(app, event)
            value = app.ComponentofBfieldDropDown.Value;
            switch value
                case 'B.x'
                    app.s.B_choice='Bx';
                case 'B.y'
                    app.s.B_choice='By';
                case 'B.z'
                    app.s.B_choice='Bz';
                case '|B|'
                    app.s.B_choice='abs';
            end
        end

        % Value changed function: ComponentofBfieldDropDown_2
        function ComponentofBfieldDropDown_2ValueChanged(app, event)
            value = app.ComponentofBfieldDropDown_2.Value;
            switch value
                case 'B.x'
                    app.s.B_choice='Bx';
                case 'B.y'
                    app.s.B_choice='By';
                case 'B.z'
                    app.s.B_choice='Bz';
                case '|B|'
                    app.s.B_choice='abs';
            end
        end

        % Value changed function: ComponentofBfieldDropDown_3
        function ComponentofBfieldDropDown_3ValueChanged(app, event)
            value = app.ComponentofBfieldDropDown_3.Value;
            switch value
                case 'B.x'
                    app.s.B_choice='Bx';
                case 'B.y'
                    app.s.B_choice='By';
                case 'B.z'
                    app.s.B_choice='Bz';
                case '|B|'
                    app.s.B_choice='abs';
            end
        end

        % Value changed function: DimensionsofcutoutDropDown
        function DimensionsofcutoutDropDownValueChanged(app, event)
            value = app.DimensionsofcutoutDropDown.Value;
            switch value
            case 'x.y'
                app.s.dim1_str='x';
                app.s.dim2_str='y';
            case 'x.z'
                app.s.dim1_str='x';
                app.s.dim2_str='z';
            case 'y.z'
                app.s.dim1_str='y';
                app.s.dim2_str='z';
            end
        end

        % Value changed function: DimensionsofcutoutDropDown_2
        function DimensionsofcutoutDropDown_2ValueChanged(app, event)
            value = app.DimensionsofcutoutDropDown_2.Value;
            switch value
            case 'x.y'
                app.s.dim1_str='x';
                app.s.dim2_str='y';
            case 'x.z'
                app.s.dim1_str='x';
                app.s.dim2_str='z';
            case 'y.z'
                app.s.dim1_str='y';
                app.s.dim2_str='z';
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            %% Load values
            start_x=app.x1.Value;
            start_y=app.y1.Value;
            end_x=app.x2.Value;
            end_y=app.y2.Value;
            end_h=app.h.Value;
            precision=str2double(app.MeasuringresolutionmmDropDown.Value);
            %probe_size in radius
            probe_size=8;
            size_x=app.size_x.Value;
            size_y=app.size_y.Value;
            height=app.size_h.Value;
            %% Bottom part
            bot_nlodret=floor((start_y-probe_size)/precision);
            bot_nvandret=floor(size_x/precision);
            %x start out in
            x = 0:precision:size_x;
            y = repmat(start_y-probe_size-bot_nlodret*precision,1,length(0:precision:size_x));
            
            for i=1:1:bot_nlodret
                if mod(i,2) == 0
                    x=[x,0:precision:size_x];
                else
                    x=[x,fliplr(0:precision:size_x)];
                end
                y=[y,repmat(start_y-probe_size-(bot_nlodret-i)*precision,1,length(0:precision:size_x))];
            end
            
            %% Left or right part
            if mod(bot_nlodret,2)==1
                %Left
                left_nlodret=ceil(((end_y+probe_size)-(start_y-probe_size+precision))/precision);
                for i=1:1:left_nlodret
                    if mod(i,2) == 0
                        x=[x,start_x-probe_size:-precision:0];
                    else
                        x=[x,fliplr(start_x-probe_size:-precision:0)];
                        
                    end
                    y=[y,repmat(start_y-probe_size+precision*i,1,length(start_x-probe_size:-precision:0))];
                end
                
            else
                %Right
                right_nlodret=ceil(((end_y+probe_size)-(start_y-probe_size+precision))/precision);
                for i=1:1:right_nlodret
                    if mod(i,2) == 0
                        x=[x,end_x+probe_size:precision:size_x];
                    else
                        x=[x,fliplr(end_x+probe_size:precision:size_x)];
                    end
                    y=[y,repmat(start_y-probe_size+precision*i,1,length(end_x+probe_size:precision:size_x))];
                end
            end
            
            %% Top part
            top_nlodret=floor((size_y-end_y-probe_size)/precision);
            top_nvandret=floor(size_x/precision);
            % Starting from left
            if mod(bot_nlodret,2)==1
                for i=0:1:top_nvandret
                   if mod(i,2)==0
                       y=[y,end_y+probe_size:precision:size_y];
                   else
                       y=[y,fliplr(end_y+probe_size:precision:size_y)];
                   end
                x = [x,repmat(i*precision,1,length(end_y+probe_size:precision:size_y))];
                end
            %Starting from right    
            else
                for i=top_nvandret:-1:0
                   if mod(i,2)==0
                       y=[y,end_y+probe_size:precision:size_y];
                   else
                       y=[y,fliplr(end_y+probe_size:precision:size_y)];
                   end
                x = [x,repmat(i*precision,1,length(end_y+probe_size:precision:size_y))];
                end
            end
            
            %% Remaining left or right part
            if mod(bot_nlodret,2)==0
                %Left
                left_nlodret=ceil(((end_y+probe_size)-(start_y-probe_size+precision))/precision);
                for i=1:1:left_nlodret
                    if mod(i,2) == 0
                        x=[x,start_x-probe_size:-precision:0];
                    else
                        x=[x,fliplr(start_x-probe_size:-precision:0)];
                        
                    end
                    y=[y,repmat(end_y+probe_size-precision*i,1,length(start_x-probe_size:-precision:0))];
                end
                
            else
                %Right
                right_nlodret=ceil(((end_y+probe_size)-(start_y-probe_size+precision))/precision);
                for i=1:1:right_nlodret
                    if mod(i,2) == 0
                        x=[x,end_x+probe_size:precision:size_x];
                    else
                        x=[x,fliplr(end_x+probe_size:precision:size_x)];
                    end
                    y=[y,repmat(end_y+probe_size-precision*i,1,length(end_x+probe_size:precision:size_x))];
                end
            end
            meas=ones(1,length(x));
            if mod(bot_nlodret,2)==1
                x=[x,x(length(x))];
                y=[y,mod(start_y-probe_size,precision)];
                meas=[meas,0];
            end
            x=[x,x(1)];
            y=[y,y(1)];
            meas=[meas,0];
            %% Plot af punkter
            figure(1);
            subplot(1,2,1);
            marg_x=[-probe_size+start_x,probe_size+end_x,probe_size+end_x,-probe_size+start_x];
            marg_y=[-probe_size+start_y,-probe_size+start_y,probe_size+end_y,probe_size+end_y];
            marg=fill(marg_x,marg_y,'-g','edgecolor','none');
            hold on            
            set(marg,'facealpha',0.5)
            box_x=[start_x,end_x,end_x,start_x];
            boc_y=[start_y,start_y,end_y,end_y];
            fill(box_x,boc_y,'r');
            plot(x,y,'LineWidth', 4);
            daspect([1 1 1]);
            hold off
            title('Plot of first layer around object');
            xlabel('x [mm]');
            ylabel('y [mm]');
            %%
            n=length(x);
            x_temp=x;
            y_temp=y;
            meas_temp=meas;
            %% Adding z layer at z=0
            z=zeros(1,n);
            %% Adding z layers until top of object
            hn=floor(end_h/precision);
            for i=hn:-1:1
                x=[x,x_temp];
                y=[y,y_temp];
                z=[z,repmat(end_h-i*precision,1,n)];
                meas=[meas,meas_temp];
            end
            yn=floor(size_y/precision);
            offset_ystart=mod(start_y-probe_size,precision);
            top_x=0:precision:size_x;
            top_y=repmat(offset_ystart,1,length(0:precision:size_x));
            for i=1:1:yn
                if mod(i,2) == 0
                    top_x=[top_x,0:precision:size_x];
                else
                    top_x=[top_x,fliplr(0:precision:size_x)];
                end
                top_y=[top_y,repmat(offset_ystart+i*precision,1,length(0:precision:size_x))];
            end
            top_x=[top_x,0];
            top_y=[top_y,offset_ystart];
            zn=floor((height-end_h)/precision);
            for i=0:1:zn
                x=[x,top_x];
                y=[y,top_y];
                z=[z,repmat(end_h+i*precision,1,length(top_x))];
                meas=[meas,ones(1,length(top_x)-1),0];
            end    
            
            %% 3d scatter plot
            subplot(1,2,2);
            %scatter3(x,y,z);
            plot3(x,y,z);
            title('Plot of final grid generated around object');
            xlabel('x [mm]');
            ylabel('y [mm]');
            zlabel('z [mm]');
            daspect([1 1 1]);
            
            %% Write to CSV file
            csvwrite('desiredPosition.csv',[x;y;z;meas]');
        end

        % Button pushed function: GenerateplotButton
        function GenerateplotButtonPushed(app, event)
            x=plot3dScatter(app.field,app.s);
            figure(1);
            plot3dScatter(app.field,app.s);
            if(x==1)
                msgbox('The selected cut out has less than 10 data values, thus making the plot vague');
                x=0;
            end
        end

        % Button pushed function: GenerateplotButton_2
        function GenerateplotButton_2Pushed(app, event)
            plot4dScatter(app.field,app.s);
            figure(1);
            plot4dScatter(app.field,app.s);
        end

        % Button pushed function: GenerateplotButton_3
        function GenerateplotButton_3Pushed(app, event)
            x=plotSurface(app.field,app.s);
            figure(1)
            plotSurface(app.field,app.s);
            if(x==1)
                msgbox('The selected cut out has less than 10 data values, thus making the plot vague');
                x=0;
            end
        end

        % Button pushed function: GenerateplotButton_4
        function GenerateplotButton_4Pushed(app, event)
            quiverPlot(app.field, app.s);
            figure(1);
            quiverPlot(app.field, app.s);
        end

        % Button pushed function: LoadmeasurementsButton
        function LoadmeasurementsButtonPushed(app, event)
    [filename pathname]=uigetfile({'*.csv'}, 'File Selector');
    cd(pathname);
    app.data=csvread(filename);
    app.field=measVoltages2Field(app.data);
        end

        % Value changed function: PercentageDatacutoffEditField
        function PercentageDatacutoffEditFieldValueChanged(app, event)
            value = app.PercentageDatacutoffEditField.Value;
            app.s.sort=value/100;
        end

        % Value changed function: PercentageofdatatobekeptEditField
        function PercentageofdatatobekeptEditFieldValueChanged(app, event)
            value = app.PercentageofdatatobekeptEditField.Value;
            app.s.sort=value/100;
        end

        % Value changed function: PositionofcutoutEditField
        function PositionofcutoutEditFieldValueChanged(app, event)
            value = app.PositionofcutoutEditField.Value;
            app.s.dim3_min=value-0.5;
            app.s.dim3_max=value+0.5;
        end

        % Value changed function: PositionofcutoutEditField_2
        function PositionofcutoutEditField_2ValueChanged(app, event)
            value = app.PositionofcutoutEditField_2.Value;
            app.s.dim3_min=value-0.5;
            app.s.dim3_max=value+0.5;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitializationTab = uitab(app.TabGroup);
            app.InitializationTab.Title = 'Initialization';
            app.InitializationTab.Units = 'pixels';
            app.FirstcoordinateofobjectPanel = uipanel(app.InitializationTab);
            app.FirstcoordinateofobjectPanel.Title = 'First coordinate of object';
            app.ymmLabel = uilabel(app.FirstcoordinateofobjectPanel);
            app.ymmLabel.Text = 'y [mm]';
            app.y1 = uieditfield(app.FirstcoordinateofobjectPanel, 'numeric');
            app.y1.Limits = [0 310];
            app.xmmLabel = uilabel(app.FirstcoordinateofobjectPanel);
            app.xmmLabel.Text = 'x [mm]';
            app.x1 = uieditfield(app.FirstcoordinateofobjectPanel, 'numeric');
            app.x1.Limits = [0 340];
            app.SecondcoordinateofobjectPanel = uipanel(app.InitializationTab);
            app.SecondcoordinateofobjectPanel.Title = 'Second coordinate of object';
            app.ymmLabel_2 = uilabel(app.SecondcoordinateofobjectPanel);
            app.ymmLabel_2.Text = 'y [mm]';
            app.y2 = uieditfield(app.SecondcoordinateofobjectPanel, 'numeric');
            app.y2.Limits = [0 310];
            app.xmmLabel_2 = uilabel(app.SecondcoordinateofobjectPanel);
            app.xmmLabel_2.Text = 'x [mm]';
            app.x2 = uieditfield(app.SecondcoordinateofobjectPanel, 'numeric');
            app.x2.Limits = [0 340];
            app.HeightofobjectPanel = uipanel(app.InitializationTab);
            app.HeightofobjectPanel.Title = 'Height of object';
            app.hmmLabel = uilabel(app.HeightofobjectPanel);
            app.hmmLabel.Text = 'h [mm]';
            app.h = uieditfield(app.HeightofobjectPanel, 'numeric');
            app.h.Limits = [0 40];
            app.SizeofmeasurementareaPanel = uipanel(app.InitializationTab);
            app.SizeofmeasurementareaPanel.Title = 'Size of measurement area';
            app.ymmLabel_3 = uilabel(app.SizeofmeasurementareaPanel);
            app.ymmLabel_3.Text = 'y [mm]';
            app.size_y = uieditfield(app.SizeofmeasurementareaPanel, 'numeric');
            app.size_y.Limits = [0 310];
            app.xmmLabel_3 = uilabel(app.SizeofmeasurementareaPanel);
            app.xmmLabel_3.Text = 'x [mm]';
            app.size_x = uieditfield(app.SizeofmeasurementareaPanel, 'numeric');
            app.size_x.Limits = [0 340];
            app.hmmLabel_2 = uilabel(app.SizeofmeasurementareaPanel);
            app.hmmLabel_2.Text = 'h [mm]';
            app.size_h = uieditfield(app.SizeofmeasurementareaPanel, 'numeric');
            app.size_h.Limits = [0 40];
            app.MeasuringresolutionmmDropDownLabel = uilabel(app.InitializationTab);
            app.MeasuringresolutionmmDropDownLabel.Text = 'Measuring resolution [mm]';
            app.MeasuringresolutionmmDropDown = uidropdown(app.InitializationTab);
            app.MeasuringresolutionmmDropDown.Items = {'1', '2', '5', '10', '25', '50'};
            app.MeasuringresolutionmmDropDown.Value = '2';
            app.GenerateButton = uibutton(app.InitializationTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.VisualizationTab.Units = 'pixels';
            app.SurfaceplotPanel = uipanel(app.VisualizationTab);
            app.SurfaceplotPanel.Title = 'Surface plot';
            app.DimensionsofcutoutDropDown_2Label = uilabel(app.SurfaceplotPanel);
            app.DimensionsofcutoutDropDown_2Label.Text = 'Dimensions of cutout';
            app.DimensionsofcutoutDropDown_2 = uidropdown(app.SurfaceplotPanel);
            app.DimensionsofcutoutDropDown_2.Items = {'x.y', 'x.z', 'y.z'};
            app.DimensionsofcutoutDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DimensionsofcutoutDropDown_2ValueChanged, true);
            app.DimensionsofcutoutDropDown_2.Value = 'x.y';
            app.PositionofcutoutEditField_2Label = uilabel(app.SurfaceplotPanel);
            app.PositionofcutoutEditField_2Label.Text = 'Position of cutout';
            app.PositionofcutoutEditField_2 = uieditfield(app.SurfaceplotPanel, 'numeric');
            app.PositionofcutoutEditField_2.ValueChangedFcn = createCallbackFcn(app, @PositionofcutoutEditField_2ValueChanged, true);
            app.PositionofcutoutEditField_2.Limits = [0 Inf];
            app.ComponentofBfieldDropDown_3Label = uilabel(app.SurfaceplotPanel);
            app.ComponentofBfieldDropDown_3Label.Text = 'Component of B-field';
            app.ComponentofBfieldDropDown_3 = uidropdown(app.SurfaceplotPanel);
            app.ComponentofBfieldDropDown_3.Items = {'B.x', 'B.y', 'B.z', '|B|'};
            app.ComponentofBfieldDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ComponentofBfieldDropDown_3ValueChanged, true);
            app.ComponentofBfieldDropDown_3.Value = 'B.x';
            app.GenerateplotButton_3 = uibutton(app.SurfaceplotPanel, 'push');
            app.GenerateplotButton_3.ButtonPushedFcn = createCallbackFcn(app, @GenerateplotButton_3Pushed, true);
            app.GenerateplotButton_3.Text = 'Generate plot';
            app.DscatterplotPanel_2 = uipanel(app.VisualizationTab);
            app.DscatterplotPanel_2.Title = '4D scatterplot';
            app.ComponentofBfieldDropDown_2Label = uilabel(app.DscatterplotPanel_2);
            app.ComponentofBfieldDropDown_2Label.Text = 'Component of B-field';
            app.ComponentofBfieldDropDown_2 = uidropdown(app.DscatterplotPanel_2);
            app.ComponentofBfieldDropDown_2.Items = {'B.x', 'B.y', 'B.z', '|B|'};
            app.ComponentofBfieldDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ComponentofBfieldDropDown_2ValueChanged, true);
            app.ComponentofBfieldDropDown_2.Value = 'B.x';
            app.PercentageofdatatobekeptEditFieldLabel = uilabel(app.DscatterplotPanel_2);
            app.PercentageofdatatobekeptEditFieldLabel.Text = 'Percentage of data to be kept';
            app.PercentageofdatatobekeptEditField = uieditfield(app.DscatterplotPanel_2, 'numeric');
            app.PercentageofdatatobekeptEditField.ValueChangedFcn = createCallbackFcn(app, @PercentageofdatatobekeptEditFieldValueChanged, true);
            app.GenerateplotButton_2 = uibutton(app.DscatterplotPanel_2, 'push');
            app.GenerateplotButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateplotButton_2Pushed, true);
            app.GenerateplotButton_2.Text = 'Generate plot';
            app.DscatterplotPanel = uipanel(app.VisualizationTab);
            app.DscatterplotPanel.Title = '3D scatterplot';
            app.PositionofcutoutEditFieldLabel = uilabel(app.DscatterplotPanel);
            app.PositionofcutoutEditFieldLabel.Text = 'Position of cutout';
            app.PositionofcutoutEditField = uieditfield(app.DscatterplotPanel, 'numeric');
            app.PositionofcutoutEditField.ValueChangedFcn = createCallbackFcn(app, @PositionofcutoutEditFieldValueChanged, true);
            app.PositionofcutoutEditField.Limits = [0 Inf];
            app.GenerateplotButton = uibutton(app.DscatterplotPanel, 'push');
            app.GenerateplotButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateplotButtonPushed, true);
            app.GenerateplotButton.Text = 'Generate plot';
            app.ComponentofBfieldDropDownLabel = uilabel(app.DscatterplotPanel);
            app.ComponentofBfieldDropDownLabel.Text = 'Component of B-field';
            app.ComponentofBfieldDropDown = uidropdown(app.DscatterplotPanel);
            app.ComponentofBfieldDropDown.Items = {'B.x', 'B.y', 'B.z', '|B|'};
            app.ComponentofBfieldDropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentofBfieldDropDownValueChanged, true);
            app.ComponentofBfieldDropDown.Value = 'B.x';
            app.DimensionsofcutoutDropDownLabel = uilabel(app.DscatterplotPanel);
            app.DimensionsofcutoutDropDownLabel.Text = 'Dimensions of cutout';
            app.DimensionsofcutoutDropDown = uidropdown(app.DscatterplotPanel);
            app.DimensionsofcutoutDropDown.Items = {'x.y', 'x.z', 'y.z'};
            app.DimensionsofcutoutDropDown.ValueChangedFcn = createCallbackFcn(app, @DimensionsofcutoutDropDownValueChanged, true);
            app.DimensionsofcutoutDropDown.Value = 'x.y';
            app.LoadmeasurementsButton = uibutton(app.VisualizationTab, 'push');
            app.LoadmeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmeasurementsButtonPushed, true);
            app.LoadmeasurementsButton.Text = 'Load measurements';
            app.QuiverplotPanel = uipanel(app.VisualizationTab);
            app.QuiverplotPanel.Title = 'Quiver plot';
            app.PercentageDatacutoffEditFieldLabel = uilabel(app.QuiverplotPanel);
            app.PercentageDatacutoffEditFieldLabel.Text = 'Percentage Data cutoff';
            app.PercentageDatacutoffEditField = uieditfield(app.QuiverplotPanel, 'numeric');
            app.PercentageDatacutoffEditField.ValueChangedFcn = createCallbackFcn(app, @PercentageDatacutoffEditFieldValueChanged, true);
            app.GenerateplotButton_4 = uibutton(app.QuiverplotPanel, 'push');
            app.GenerateplotButton_4.ButtonPushedFcn = createCallbackFcn(app, @GenerateplotButton_4Pushed, true);
            app.GenerateplotButton_4.Text = 'Generate plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end