classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        firstcord               matlab.ui.control.Label
        secondcord              matlab.ui.control.Label
        PrecisionDropDownLabel  matlab.ui.control.Label
        PrecisionDropDown       matlab.ui.control.DropDown
        yEditFieldLabel         matlab.ui.control.Label
        y1cord                  matlab.ui.control.NumericEditField
        xEditFieldLabel         matlab.ui.control.Label
        x1cord                  matlab.ui.control.NumericEditField
        yEditField_2Label       matlab.ui.control.Label
        y2cord                  matlab.ui.control.NumericEditField
        xEditField_2Label       matlab.ui.control.Label
        x2cord                  matlab.ui.control.NumericEditField
        heightlabel             matlab.ui.control.Label
        hLabel                  matlab.ui.control.Label
        height                  matlab.ui.control.NumericEditField
        GeneratematrixButton    matlab.ui.control.Button
        hLabel_2                matlab.ui.control.Label
        hsize                   matlab.ui.control.NumericEditField
        sizelabel               matlab.ui.control.Label
        yEditField_2Label_2     matlab.ui.control.Label
        ysize                   matlab.ui.control.NumericEditField
        xEditField_2Label_2     matlab.ui.control.Label
        xsize                   matlab.ui.control.NumericEditField
        InitprogramLabel        matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: GeneratematrixButton
        function GeneratematrixButtonPushed(app, event)
            %% Load values
            start_x=app.x1cord.Value;
            start_y=app.y1cord.Value;
            end_x=app.x2cord.Value;
            end_y=app.y2cord.Value;
            end_h=app.height.Value;
            precision=str2double(app.PrecisionDropDown.Value);
            %probe_size in radius
            probe_size=15;
            size_x=app.xsize.Value;
            size_y=app.ysize.Value;
            height=app.hsize.Value;
            %% Bottom part
            bot_nlodret=floor((start_y-probe_size)/precision);
            bot_nvandret=floor(size_x/precision);
            %x start out in
            x = 0:precision:size_x;
            y = repmat(start_y-probe_size-bot_nlodret*precision,1,length(0:precision:size_x));
            
            for i=1:1:bot_nlodret
                if mod(i,2) == 0
                    x=[x,0:precision:size_x];
                else
                    x=[x,fliplr(0:precision:size_x)];
                end
                y=[y,repmat(start_y-probe_size-(bot_nlodret-i)*precision,1,length(0:precision:size_x))];
            end
            
            %% Left or right part
            if mod(bot_nlodret,2)==1
                %Left
                left_nlodret=ceil(((end_y+probe_size)-(start_y-probe_size+precision))/precision);
                for i=1:1:left_nlodret
                    if mod(i,2) == 0
                        x=[x,start_x-probe_size:-precision:0];
                    else
                        x=[x,fliplr(start_x-probe_size:-precision:0)];
                        
                    end
                    y=[y,repmat(start_y-probe_size+precision*i,1,length(start_x-probe_size:-precision:0))];
                end
                
            else
                %Right
                right_nlodret=ceil(((end_y+probe_size)-(start_y-probe_size+precision))/precision);
                for i=1:1:right_nlodret
                    if mod(i,2) == 0
                        x=[x,end_x+probe_size:precision:size_x];
                    else
                        x=[x,fliplr(end_x+probe_size:precision:size_x)];
                    end
                    y=[y,repmat(start_y-probe_size+precision*i,1,length(end_x+probe_size:precision:size_x))];
                end
            end
            
            %% Top part
            top_nlodret=floor((size_y-end_y-probe_size)/precision);
            top_nvandret=floor(size_x/precision);
            % Starting from left
            if mod(bot_nlodret,2)==1
                for i=0:1:top_nvandret
                   if mod(i,2)==0
                       y=[y,end_y+probe_size:precision:size_y];
                   else
                       y=[y,fliplr(end_y+probe_size:precision:size_y)];
                   end
                x = [x,repmat(i*precision,1,length(end_y+probe_size:precision:size_y))];
                end
            %Starting from right    
            else
                for i=top_nvandret:-1:0
                   if mod(i,2)==0
                       y=[y,end_y+probe_size:precision:size_y];
                   else
                       y=[y,fliplr(end_y+probe_size:precision:size_y)];
                   end
                x = [x,repmat(i*precision,1,length(end_y+probe_size:precision:size_y))];
                end
            end
            
            %% Remaining left or right part
            if mod(bot_nlodret,2)==0
                %Left
                left_nlodret=ceil(((end_y+probe_size)-(start_y-probe_size+precision))/precision);
                for i=1:1:left_nlodret
                    if mod(i,2) == 0
                        x=[x,start_x-probe_size:-precision:0];
                    else
                        x=[x,fliplr(start_x-probe_size:-precision:0)];
                        
                    end
                    y=[y,repmat(end_y+probe_size-precision*i,1,length(start_x-probe_size:-precision:0))];
                end
                
            else
                %Right
                right_nlodret=ceil(((end_y+probe_size)-(start_y-probe_size+precision))/precision);
                for i=1:1:right_nlodret
                    if mod(i,2) == 0
                        x=[x,end_x+probe_size:precision:size_x];
                    else
                        x=[x,fliplr(end_x+probe_size:precision:size_x)];
                    end
                    y=[y,repmat(end_y+probe_size-precision*i,1,length(end_x+probe_size:precision:size_x))];
                end
            end
            meas=ones(1,length(x));
            if mod(bot_nlodret,2)==1
                x=[x,x(length(x))];
                y=[y,mod(start_y-probe_size,precision)];
                meas=[meas,0];
            end
            
            %% Plot af punkter
            figure(1);
            marg_x=[-probe_size+start_x,probe_size+end_x,probe_size+end_x,-probe_size+start_x];
            marg_y=[-probe_size+start_y,-probe_size+start_y,probe_size+end_y,probe_size+end_y];
            marg=fill(marg_x,marg_y,'-g','edgecolor','none');
            hold on            
            set(marg,'facealpha',0.5)
            box_x=[start_x,end_x,end_x,start_x];
            boc_y=[start_y,start_y,end_y,end_y];
            fill(box_x,boc_y,'r');
            plot(x,y,'LineWidth', 4);
            daspect([1 1 1]);
            hold off
            %%
            n=length(x);
            x_temp=x;
            y_temp=y;
            meas_temp=meas;
            %% Adding z layer at z=0
            z=zeros(1,n);
            %% Adding z layers until top of object
            hn=floor(end_h/precision);
            for i=hn:-1:1
                x=[x,x_temp];
                y=[y,y_temp];
                z=[z,repmat(end_h-i*precision,1,n)];
                meas=[meas,meas_temp];
            end
            yn=floor(size_y/precision);
            offset_ystart=mod(start_y-probe_size,precision);
            top_x=0:precision:size_x;
            top_y=repmat(offset_ystart,1,length(0:precision:size_x));
            for i=1:1:yn
                if mod(i,2) == 0
                    top_x=[top_x,0:precision:size_x];
                else
                    top_x=[top_x,fliplr(0:precision:size_x)];
                end
                top_y=[top_y,repmat(offset_ystart+i*precision,1,length(0:precision:size_x))];
            end
            zn=floor((height-end_h)/precision);
            for i=0:1:zn
                x=[x,top_x];
                y=[y,top_y];
                z=[z,repmat(end_h+i*precision,1,length(top_x))];
                meas=[meas,ones(1,length(top_x))];
            end    
            
            %% 3d scatter plot
            figure(2);
            %scatter3(x,y,z);
            plot3(x,y,z);
            daspect([1 1 1]);
            
            %% Write to CSV file
            csvwrite('diller.csv',[x;y;z;meas]');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.firstcord = uilabel(app.UIFigure);
            app.firstcord.Text = 'First coordinate of object';
            app.secondcord = uilabel(app.UIFigure);
            app.secondcord.Text = 'Second coordinate of object';
            app.PrecisionDropDownLabel = uilabel(app.UIFigure);
            app.PrecisionDropDownLabel.Text = 'Precision';
            app.PrecisionDropDown = uidropdown(app.UIFigure);
            app.PrecisionDropDown.Items = {'5', '10', '25'};
            app.PrecisionDropDown.Value = '5';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.y1cord = uieditfield(app.UIFigure, 'numeric');
            app.y1cord.Limits = [0 Inf];
            app.y1cord.Value = 50;
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.x1cord = uieditfield(app.UIFigure, 'numeric');
            app.x1cord.Limits = [0 Inf];
            app.x1cord.Value = 50;
            app.yEditField_2Label = uilabel(app.UIFigure);
            app.yEditField_2Label.Text = 'y';
            app.y2cord = uieditfield(app.UIFigure, 'numeric');
            app.y2cord.Limits = [0 Inf];
            app.y2cord.Value = 100;
            app.xEditField_2Label = uilabel(app.UIFigure);
            app.xEditField_2Label.Text = 'x';
            app.x2cord = uieditfield(app.UIFigure, 'numeric');
            app.x2cord.Limits = [0 Inf];
            app.x2cord.Value = 100;
            app.heightlabel = uilabel(app.UIFigure);
            app.heightlabel.Text = 'Height of object';
            app.hLabel = uilabel(app.UIFigure);
            app.hLabel.Text = 'h';
            app.height = uieditfield(app.UIFigure, 'numeric');
            app.height.Limits = [0 Inf];
            app.height.Value = 50;
            app.GeneratematrixButton = uibutton(app.UIFigure, 'push');
            app.GeneratematrixButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratematrixButtonPushed, true);
            app.GeneratematrixButton.Text = 'Generate matrix';
            app.hLabel_2 = uilabel(app.UIFigure);
            app.hLabel_2.Text = 'h';
            app.hsize = uieditfield(app.UIFigure, 'numeric');
            app.hsize.Limits = [0 Inf];
            app.hsize.Value = 100;
            app.sizelabel = uilabel(app.UIFigure);
            app.sizelabel.Text = 'Size of measurement area';
            app.yEditField_2Label_2 = uilabel(app.UIFigure);
            app.yEditField_2Label_2.Text = 'y';
            app.ysize = uieditfield(app.UIFigure, 'numeric');
            app.ysize.Limits = [0 Inf];
            app.ysize.Value = 200;
            app.xEditField_2Label_2 = uilabel(app.UIFigure);
            app.xEditField_2Label_2.Text = 'x';
            app.xsize = uieditfield(app.UIFigure, 'numeric');
            app.xsize.Limits = [0 Inf];
            app.xsize.Value = 200;
            app.InitprogramLabel = uilabel(app.UIFigure);
            app.InitprogramLabel.Text = 'Init program';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end